classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SimulateTimeEditField       matlab.ui.control.NumericEditField
        SimulateTimeEditFieldLabel  matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        REditField                  matlab.ui.control.NumericEditField
        REditFieldLabel             matlab.ui.control.Label
        KEditField                  matlab.ui.control.NumericEditField
        KEditFieldLabel             matlab.ui.control.Label
        BEditField                  matlab.ui.control.NumericEditField
        BEditFieldLabel             matlab.ui.control.Label
        JEditField                  matlab.ui.control.NumericEditField
        JEditFieldLabel             matlab.ui.control.Label
        LEditField                  matlab.ui.control.NumericEditField
        LEditFieldLabel             matlab.ui.control.Label
        VEditField                  matlab.ui.control.NumericEditField
        VEditFieldLabel             matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: BEditField, JEditField, KEditField,
        % LEditField, 
        % ...and 3 other components
        function Run(app, event)
% Set parameters from GUI inputs
V = app.VEditField.Value;   % Voltage
R = app.REditField.Value;   % Resistance
L = app.LEditField.Value;   % Inductance
K = app.KEditField.Value;   % Motor Constant
B = app.BEditField.Value;   % Friction
J = app.JEditField.Value;   % Inertia
n = app.SimulateTimeEditField.Value; % Simulation time from GUI

% Assign values to MATLAB workspace
assignin('base', 'V', V);
assignin('base', 'R', R);
assignin('base', 'L', L);
assignin('base', 'K', K);
assignin('base', 'B', B);
assignin('base', 'J', J);

% Run Simulink model with dynamic stop time
simOut = sim('Analysis', 'StopTime', num2str(n)); % Replace 'Analysis' with your model's name

% Get the simulation output (angular velocity)
output = simOut.w; % Assuming 'w' is logged in the Simulink model

% Plot on the GUI
plot(app.UIAxes, output.time, output.signals.values);
title(app.UIAxes, 'Angular Velocity (\omega)');
xlabel(app.UIAxes, 'Time (s)');
ylabel(app.UIAxes, 'Angular Velocity (rad/s)');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VEditFieldLabel = uilabel(app.UIFigure);
            app.VEditFieldLabel.Text = 'V';
            app.VEditField = uieditfield(app.UIFigure, 'numeric');
            app.VEditField.ValueChangedFcn = createCallbackFcn(app, @Run, true);
            app.VEditField.Value = 240;
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.LEditField.ValueChangedFcn = createCallbackFcn(app, @Run, true);
            app.LEditField.Value = 0.02;
            app.JEditFieldLabel = uilabel(app.UIFigure);
            app.JEditFieldLabel.Text = 'J';
            app.JEditField = uieditfield(app.UIFigure, 'numeric');
            app.JEditField.ValueChangedFcn = createCallbackFcn(app, @Run, true);
            app.JEditField.Value = 1;
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditField.ValueChangedFcn = createCallbackFcn(app, @Run, true);
            app.KEditFieldLabel = uilabel(app.UIFigure);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.UIFigure, 'numeric');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @Run, true);
            app.KEditField.Value = 1;
            app.REditFieldLabel = uilabel(app.UIFigure);
            app.REditFieldLabel.Text = 'R';
            app.REditField = uieditfield(app.UIFigure, 'numeric');
            app.REditField.ValueChangedFcn = createCallbackFcn(app, @Run, true);
            app.REditField.Value = 240;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunButton.Text = 'Run';
            app.SimulateTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulateTimeEditFieldLabel.Text = 'Simulate Time';
            app.SimulateTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulateTimeEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Associate the Simulink Model
            app.Simulation = simulation('Analysis');

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end